classdef learningquiz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        WaldoCircle                matlab.ui.control.Label
        RevealWaldoButton          matlab.ui.control.Button
        Switch                     matlab.ui.control.Switch
        Spinner                    matlab.ui.control.Spinner
        SubmitSpinnerButton        matlab.ui.control.Button
        PlayAudioButton            matlab.ui.control.Button
        Slider                     matlab.ui.control.Slider
        SliderLabel                matlab.ui.control.Label
        Image7                     matlab.ui.control.Image
        Image6                     matlab.ui.control.Image
        Switch_3                   matlab.ui.control.Switch
        Image5                     matlab.ui.control.Image
        StopTimerButton            matlab.ui.control.Button
        StartTimerButton           matlab.ui.control.Button
        SwitchLabel                matlab.ui.control.Label
        QuestionNumberLabel        matlab.ui.control.Label
        SubmitButton               matlab.ui.control.Button
        Switch_2                   matlab.ui.control.Switch
        Switch_2Label              matlab.ui.control.Label
        Switch_4                   matlab.ui.control.Switch
        Switch_4Label              matlab.ui.control.Label
        Switch_5                   matlab.ui.control.Switch
        Switch_5Label              matlab.ui.control.Label
        Switch_3Label              matlab.ui.control.Label
        Switch_6                   matlab.ui.control.Switch
        Switch_6Label              matlab.ui.control.Label
        Image4                     matlab.ui.control.Image
        Image3                     matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        SubmitRatingButton         matlab.ui.control.Button
        ResultsPanel               matlab.ui.container.Panel
        DescriptionLabel           matlab.ui.control.TextArea
        TextAreaLabel              matlab.ui.control.Label
        LearningStyleResultsLabel  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        UIAxes                     matlab.ui.control.UIAxes
        SkipButton                 matlab.ui.control.Button
        OptionButton3              matlab.ui.control.Button
        OptionButton2              matlab.ui.control.Button
        QuestionLabel              matlab.ui.control.Label
        OptionButton1              matlab.ui.control.Button
    end

    
    properties (Access = private)
        CurrentQuestionIndex = 1 %tracks what question the user is on
        Scores = struct('Visual', 0, 'Auditory', 0, 'Kinesthetic', 0, 'None', 0) %stores the scores for each category of learning style
        QuizData
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % define quiz questions, options, and corresponding learning style 
            %assessing personality traits
           app.QuizData(1).question = "What characteristics best describe you?";
           app.QuizData(1).options = {"Creative, vivid imagination, detail-oriented", "A great listener and public speaker", "Active, enjoy experimenting"};
           app.QuizData(1).type = {"Visual", "Auditory", "Kinesthetic"};
           app.QuizData(1).questionType = "multiple choice"; %defining what type of question this is: multiple choice
           
           %assessing career choices and future goals
           app.QuizData(2).question = "What career path would you be most inclined to pursue?";
           app.QuizData(2).options = {"Architect or Artist", "Surgeon or Electrician", "Attorney or Therapist"};
           app.QuizData(2).type = {"Visual", "Kinesthetic", "Auditory"};
           app.QuizData(2).questionType = "multiple choice";

           %assessing exam recall strategy
           app.QuizData(3).question = "When you are trying to think of the correct answer on an exam, what do you do?";
           app.QuizData(3).options = {"Replay the mnemonics I’ve memorized in my head", "Visualize where I wrote the information in my notes", "Think back to the experiments we performed in class"};
           app.QuizData(3).type = {"Auditory", "Visual", "Kinesthetic"};
           app.QuizData(3).questionType = "multiple choice";
           
          %agreement statement for visual category
          app.QuizData(4).question = "I find diagrams/images helpful when learning (1 = strongly disagree, 5 = strongly agree)";
          app.QuizData(4).type = "Visual";
          app.QuizData(4).questionType = "slider"; %new type of question: slider

          %agreement statement for kinesthetic category
          app.QuizData(5).question = "Rewriting notes is the best way to memorize content (1 = strongly disagree, 5 = strongly agree)";
          app.QuizData(5).type = "Kinesthetic";
          app.QuizData(5).questionType = "slider";

          %agreement statement for auditory category
          app.QuizData(6).question = "I replay lectures to help me understand new concepts (1 = strongly disagree, 5 = strongly agree)";
          app.QuizData(6).type = "Auditory";
          app.QuizData(6).questionType = "slider";

          %visual- based question using images to assess learning
          %environment preference
          app.QuizData(7).question = "Which environment, as pictured, would you most prefer to learn in?";
          app.QuizData(7).options = {"A peaceful coffee shop", "Outdoors", "A colorful lounge"};
          app.QuizData(7).type = {"Auditory", "Kinesthetic", "Visual"};
          app.QuizData(7).questionType = "image-based multiple choice";

          %auditory based question using our inserted clip
          app.QuizData(8).question = "Follow the instructions in the audio clip. Please only play it one time.";
          app.QuizData(8).options = {"1-3", "4-6", "I don't know"};
          app.QuizData(8).type = {"None", "Auditory", "None"};
          app.QuizData(8).questionType = "audio";

          %visual based question with time pressure
          app.QuizData(9).question = "Press the play button to start the timer. Did you find Waldo (pictured left)?";
          app.QuizData(9).options = {"Yes", "No", "Tried my best, but still no"};
          app.QuizData(9).type = {"Visual", "None", "None"};
          app.QuizData(9).questionType = "mixed";

          %kinesthetic based question -- requires participant movement
          app.QuizData(10).question = "Try this: Rotate your foot counterclockwise and your hand clockwise at the same time What happened?";
          app.QuizData(10).options = {"I did it easily", "It was difficult to coordinate, but I did it.", "I couldn't even do it once"};
          app.QuizData(10).type = {"Kinesthetic", "None", "None"};
          app.QuizData(10).questionType = "multiple choice";

          %new type of question: using switches to specifically compare
          %between two choices
          app.QuizData(11).question = "";
          app.QuizData(11).questionType = "switches";
          app.QuizData(11).SwitchLabels = {"a podcast or verbal explanation", "a diagram or infographic"; "watch demonstrations or videos", "try it myself with hands on practice"; "someone explaining them step by step", "jumping in and figuring it out as I go"};
          app.QuizData(11).SwitchTypes = {"Auditory", "Visual"; "Visual", "Kinesthetic"; "Auditory", "Kinesthetic"};

          %continuation of the previous switch question
          app.QuizData(12).question = "";
          app.QuizData(12).questionType = "switches2";
          app.QuizData(12).SwitchLabels = {"repeating it aloud or listening to recordings", "writing it down or using flashcards"; "highlighters and mind maps", "fidget tools or pacing around"; "hear the speaker's voice in my head", "remember the physical actions I performed"};
          app.QuizData(12).SwitchTypes = {"Auditory", "Visual"; "Visual", "Kinesthetic"; "Auditory", "Kinesthetic"};


           % initialize score tracking for each different category
           app.Scores.Visual = 0;
           app.Scores.Auditory = 0;
           app.Scores.Kinesthetic = 0;
           app.Scores.None = 0;
      
           %start at the first question
           app.CurrentQuestionIndex = 1;
      
           % display first question
           displayQuestion(app);
        end

       %called when an multiple choice answer is selected
        function updateScores(app, selectedIndex)
            % get the associated learning type from the selected option
            type = app.QuizData(app.CurrentQuestionIndex).type{selectedIndex};
            %increment the corresponding score
            app.Scores.(type) = app.Scores.(type) + 1;
            %move to the next question
            app.CurrentQuestionIndex = app.CurrentQuestionIndex + 1;            
           
            %check to see if the quiz has ended
            if app.CurrentQuestionIndex > length(app.QuizData)
                showResults(app); %show results if all questions have been completed
            else
                displayQuestion(app); %if quiz is not over, display next question
            end
        end
    
        %called when a slider answer is selected
        function updateSliderScore(app)
            q = app.QuizData(app.CurrentQuestionIndex); 
            sliderValue = round(app.Slider.Value); %after reading the current input, round the slider to an integer value
            app.Scores.(q.type) = app.Scores.(q.type) + sliderValue; %add this value to the overall category score
            
            %proceed to the next question
            app.CurrentQuestionIndex = app.CurrentQuestionIndex + 1;
            
            %if quiz is over, show results
            % if not, continue to the next question
            if app.CurrentQuestionIndex > length(app.QuizData)
                showResults(app);
            else
              displayQuestion(app);
            end
        end

        %called when a switch style question answer choice is being selected 
        function processSwitchSelections(app)
            q = app.QuizData(app.CurrentQuestionIndex);
            %comparing auditory versus visual
            % get state of each switch and update scores
            AVswitches = {app.Switch, app.Switch_4};
            for i = 1:length(AVswitches)
                % go through both switches and evaluate their positions
                AVcurrent_Switch = AVswitches{i};
                % add score to auditory if first item is selected; else visual
                if strcmp(AVcurrent_Switch.Value, AVcurrent_Switch.Items{1})
                    app.Scores.Auditory = app.Scores.Auditory + 1;
                else
                    app.Scores.Visual = app.Scores.Visual + 1;
                end
            end
            
            %comparing visual versus kinesthetic
            VKswitches = {app.Switch_2, app.Switch_5};
            for i = 1:length(VKswitches)
                % go through both switches and evaluate their positions
                VKcurrent_Switch = VKswitches{i};
                % add score to visual if first item is selected; else kinesthetic
                if strcmp(VKcurrent_Switch.Value, VKcurrent_Switch.Items{1})
                    app.Scores.Visual = app.Scores.Visual + 1;
                else
                    app.Scores.Kinesthetic = app.Scores.Kinesthetic + 1;
                end
            end

            %comparing auditory versus kinesthetic
            AKswitches = {app.Switch_3, app.Switch_6};
            for i = 1:length(AKswitches)
                % go through both switches and evaluate their positions
                AKcurrent_Switch = AKswitches{i};
                % add score to auditory if first item is selected; else kinesthetic
                if strcmp(AKcurrent_Switch.Value, AKcurrent_Switch.Items{1})
                    app.Scores.Auditory = app.Scores.Auditory + 1;
                else
                    app.Scores.Kinesthetic = app.Scores.Kinesthetic + 1;
                end
            end


        end

        % Button pushed function: OptionButton1
        function OptionButton1Pushed(app, event)
            clear sound; % stop any audio
            updateScores(app, 1); % update scores based on selection

        end

        % Button pushed function: OptionButton2
        function OptionButton2Pushed(app, event)
            clear sound; % stop any audio
            updateScores(app, 2); % update scores based on selection

        end

        % Button pushed function: OptionButton3
        function OptionButton3Pushed(app, event)
            clear sound; % stop any audio
            updateScores(app, 3); % update scores based on selection
        end
       
        function displayQuestion(app)
            q = app.QuizData(app.CurrentQuestionIndex);
            % display question number
            app.QuestionNumberLabel.Text = sprintf('Question %d of %d', app.CurrentQuestionIndex, length(app.QuizData));

            if strcmp(q.questionType, "slider")
                % configure UI for slider-based question (show relevant,
                % hide irrelevant)
                app.OptionButton1.Visible = 'off';
                app.OptionButton2.Visible = 'off';
                app.OptionButton3.Visible = 'off';
                app.SkipButton.Visible = 'on';
                app.Slider.Visible = 'on';
                app.SliderLabel.Visible = 'on';
                app.SubmitRatingButton.Visible = 'on';
                app.PlayAudioButton.Visible = "off";
                app.StartTimerButton.Visible = 'off';
                app.StopTimerButton.Visible = 'off';
                app.Image7.Visible = 'on';
                app.Image5.Visible = 'off';
                app.Image6.Visible = 'off';
                app.Switch.Visible = 'off';
                app.Switch_6.Visible = 'off';
                app.Switch_3.Visible = 'off';
                app.Switch_5.Visible = 'off';
                app.Switch_4.Visible = 'off';
                app.Switch_2.Visible = 'off';
                app.SwitchLabel.Visible = 'off';
                app.Switch_6Label.Visible = 'off';
                app.Switch_3Label.Visible = 'off';
                app.Switch_5Label.Visible = 'off';
                app.Switch_4Label.Visible = 'off';
                app.Switch_2Label.Visible = 'off';
                app.SubmitButton.Visible = 'off';
                app.SubmitSpinnerButton.Visible = 'off';
                app.Spinner.Visible = 'off';
                app.RevealWaldoButton.Visible = 'off';
                app.WaldoCircle.Visible = 'off';

                % set the question text
                app.QuestionLabel.Text = q.question;
                app.Slider.Value = 2.5; % Default slider value

            elseif strcmp(q.questionType, "audio")
                % configure UI for audio-based question (show relevant,
                % hide irrelevant)
               app.OptionButton1.Visible = 'off';
                app.OptionButton2.Visible = 'off';
                app.OptionButton3.Visible = 'off';
                app.SkipButton.Visible = 'on';
                app.Slider.Visible = 'off';
                app.SliderLabel.Visible = 'off';
                app.SubmitRatingButton.Visible = 'off';
                app.PlayAudioButton.Visible = "on";
                app.StartTimerButton.Visible = 'off';
                app.StopTimerButton.Visible = 'off';
                app.Image7.Visible = 'on';
                app.Image5.Visible = 'off';
                app.Image6.Visible = 'off';
                app.Image2.Visible = 'off';
                app.Image3.Visible = 'off';
                app.Image4.Visible = 'off';
                app.Switch.Visible = 'off';
                app.Switch_6.Visible = 'off';
                app.Switch_3.Visible = 'off';
                app.Switch_5.Visible = 'off';
                app.Switch_4.Visible = 'off';
                app.Switch_2.Visible = 'off';
                app.SwitchLabel.Visible = 'off';
                app.Switch_6Label.Visible = 'off';
                app.Switch_3Label.Visible = 'off';
                app.Switch_5Label.Visible = 'off';
                app.Switch_4Label.Visible = 'off';
                app.Switch_2Label.Visible = 'off';
                app.SubmitButton.Visible = 'off';
                app.SubmitSpinnerButton.Visible = 'on';
                app.Spinner.Visible = 'on';
                app.RevealWaldoButton.Visible = 'off';
                app.WaldoCircle.Visible = 'off';

               % set the question text
               app.QuestionLabel.Text = q.question;
               app.OptionButton1.Text = q.options{1};
               app.OptionButton2.Text = q.options{2};
               app.OptionButton3.Text = q.options{3};


            elseif strcmp (q.questionType, "multiple choice")
                % configure UI for mc-based question (show relevant,
                % hide irrelevant)
                app.OptionButton1.Visible = 'on';
                app.OptionButton2.Visible = 'on';
                app.OptionButton3.Visible = 'on';
                app.SkipButton.Visible = 'on';
                app.Slider.Visible = 'off';
                app.SliderLabel.Visible = 'off';
                app.SubmitRatingButton.Visible = 'off';
                app.PlayAudioButton.Visible = "off";
                app.StartTimerButton.Visible = 'off';
                app.StopTimerButton.Visible = 'off';
                app.Image7.Visible = 'on';
                app.Image5.Visible = 'off';
                app.Image6.Visible = 'off';
                app.Image2.Visible = 'off';
                app.Image3.Visible = 'off';
                app.Image4.Visible = 'off';
                app.Switch.Visible = 'off';
                app.Switch_6.Visible = 'off';
                app.Switch_3.Visible = 'off';
                app.Switch_5.Visible = 'off';
                app.Switch_4.Visible = 'off';
                app.Switch_2.Visible = 'off';
                app.SwitchLabel.Visible = 'off';
                app.Switch_6Label.Visible = 'off';
                app.Switch_3Label.Visible = 'off';
                app.Switch_5Label.Visible = 'off';
                app.Switch_4Label.Visible = 'off';
                app.Switch_2Label.Visible = 'off';
                app.SubmitButton.Visible = 'off';
                app.SubmitSpinnerButton.Visible = 'off';
                app.Spinner.Visible = 'off';
                app.RevealWaldoButton.Visible = 'off';
                app.WaldoCircle.Visible = 'off';

                % set the question text
                app.QuestionLabel.Text = q.question;
                app.OptionButton1.Text = q.options{1};
                app.OptionButton2.Text = q.options{2};
                app.OptionButton3.Text = q.options{3};


            elseif strcmp (q.questionType, "image-based multiple choice")
                % configure UI for mc with image-based question (show relevant,
                % hide irrelevant)
                app.OptionButton1.Visible = 'on';
                app.OptionButton2.Visible = 'on';
                app.OptionButton3.Visible = 'on';
                app.SkipButton.Visible = 'on';
                app.Slider.Visible = 'off';
                app.SliderLabel.Visible = 'off';
                app.SubmitRatingButton.Visible = 'off';
                app.PlayAudioButton.Visible = 'off';
                app.StartTimerButton.Visible = 'off';
                app.StopTimerButton.Visible = 'off';
                app.Image7.Visible = 'off';
                app.Image5.Visible = 'off';
                app.Image6.Visible = 'off';
                app.Image2.Visible = 'on';
                app.Image3.Visible = 'on';
                app.Image4.Visible = 'on';
                app.Switch.Visible = 'off';
                app.Switch_6.Visible = 'off';
                app.Switch_3.Visible = 'off';
                app.Switch_5.Visible = 'off';
                app.Switch_4.Visible = 'off';
                app.Switch_2.Visible = 'off';
                app.SwitchLabel.Visible = 'off';
                app.Switch_6Label.Visible = 'off';
                app.Switch_3Label.Visible = 'off';
                app.Switch_5Label.Visible = 'off';
                app.Switch_4Label.Visible = 'off';
                app.Switch_2Label.Visible = 'off';
                app.SubmitButton.Visible = 'off';
                app.SubmitSpinnerButton.Visible = 'off';
                app.Spinner.Visible = 'off';
                app.RevealWaldoButton.Visible = 'off';
                app.WaldoCircle.Visible = 'off';

                % set the question text
                app.QuestionLabel.Text = q.question;
                app.OptionButton1.Text = q.options{1};
                app.OptionButton2.Text = q.options{2};
                app.OptionButton3.Text = q.options{3};


            elseif strcmp(q.questionType, "mixed")
                % configure UI for mixed question (show relevant,
                % hide irrelevant)
                app.OptionButton1.Visible = 'on';
                app.OptionButton2.Visible = 'on';
                app.OptionButton3.Visible = 'on';
                app.SkipButton.Visible = 'on';
                app.Slider.Visible = 'off';
                app.SliderLabel.Visible = 'off';
                app.SubmitRatingButton.Visible = 'off';
                app.StartTimerButton.Visible = 'on';
                app.StopTimerButton.Visible = 'on';
                app.PlayAudioButton.Visible = "off";
                app.Image7.Visible = 'off';
                app.Image5.Visible = 'on';
                app.Image6.Visible = 'on';
                app.Image2.Visible = 'off';
                app.Image3.Visible = 'off';
                app.Image4.Visible = 'off';
                app.Switch.Visible = 'off';
                app.Switch_6.Visible = 'off';
                app.Switch_3.Visible = 'off';
                app.Switch_5.Visible = 'off';
                app.Switch_4.Visible = 'off';
                app.Switch_2.Visible = 'off';
                app.SwitchLabel.Visible = 'off';
                app.Switch_6Label.Visible = 'off';
                app.Switch_3Label.Visible = 'off';
                app.Switch_5Label.Visible = 'off';
                app.Switch_4Label.Visible = 'off';
                app.Switch_2Label.Visible = 'off';
                app.SubmitButton.Visible = 'off';
                app.SubmitSpinnerButton.Visible = 'off';
                app.Spinner.Visible = 'off';
                app.RevealWaldoButton.Visible = 'on';
                app.WaldoCircle.Visible = 'off';

                % set the question text
                app.QuestionLabel.Text = q.question;
                app.OptionButton1.Text = q.options{1};
                app.OptionButton2.Text = q.options{2};
                app.OptionButton3.Text = q.options{3};

            elseif strcmp (q.questionType, "switches")
                % configure UI for the first switch-based question (show relevant,
                % hide irrelevant)
                app.OptionButton1.Visible = 'off';
                app.OptionButton2.Visible = 'off';
                app.OptionButton3.Visible = 'off';
                app.SkipButton.Visible = 'on';
                app.Slider.Visible = 'off';
                app.SliderLabel.Visible = 'off';
                app.SubmitRatingButton.Visible = 'off';
                app.PlayAudioButton.Visible = 'off';
                app.StartTimerButton.Visible = 'off';
                app.StopTimerButton.Visible = 'off';
                app.Image7.Visible = 'off';
                app.Image5.Visible = 'off';
                app.Image6.Visible = 'off';
                app.Image2.Visible = 'off';
                app.Image3.Visible = 'off';
                app.Image4.Visible = 'off';
                app.Switch.Visible = 'on';
                app.Switch_6.Visible = 'off';
                app.Switch_3.Visible = 'on';
                app.Switch_5.Visible = 'off';
                app.Switch_4.Visible = 'off';
                app.Switch_2.Visible = 'on';
                app.SwitchLabel.Visible = 'on';
                app.Switch_6Label.Visible = 'off';
                app.Switch_3Label.Visible = 'on';
                app.Switch_5Label.Visible = 'off';
                app.Switch_4Label.Visible = 'off';
                app.Switch_2Label.Visible = 'on';
                app.QuestionLabel.Visible = 'off';
                app.SubmitButton.Visible = 'on';
                app.SubmitSpinnerButton.Visible = 'off';
                app.Spinner.Visible = 'off';
                app.RevealWaldoButton.Visible = 'off';
                app.WaldoCircle.Visible = 'off';

            elseif strcmp (q.questionType, "switches2")
                % configure UI for the second switch-based question (show relevant,
                % hide irrelevant)
                app.OptionButton1.Visible = 'off';
                app.OptionButton2.Visible = 'off';
                app.OptionButton3.Visible = 'off';
                app.SkipButton.Visible = 'on';
                app.Slider.Visible = 'off';
                app.SliderLabel.Visible = 'off';
                app.SubmitRatingButton.Visible = 'off';
                app.PlayAudioButton.Visible = 'off';
                app.StartTimerButton.Visible = 'off';
                app.StopTimerButton.Visible = 'off';
                app.Image7.Visible = 'off';
                app.Image5.Visible = 'off';
                app.Image6.Visible = 'off';
                app.Image2.Visible = 'off';
                app.Image3.Visible = 'off';
                app.Image4.Visible = 'off';
                app.Switch.Visible = 'off';
                app.Switch_6.Visible = 'on';
                app.Switch_3.Visible = 'off';
                app.Switch_5.Visible = 'on';
                app.Switch_4.Visible = 'on';
                app.Switch_2.Visible = 'off';
                app.SwitchLabel.Visible = 'off';
                app.Switch_6Label.Visible = 'on';
                app.Switch_3Label.Visible = 'off';
                app.Switch_5Label.Visible = 'on';
                app.Switch_4Label.Visible = 'on';
                app.Switch_2Label.Visible = 'off';
                app.QuestionLabel.Visible = 'off';
                app.SubmitButton.Visible = 'on';
                app.SubmitSpinnerButton.Visible = 'off';
                app.Spinner.Visible = 'off';
                app.RevealWaldoButton.Visible = 'off';
                app.WaldoCircle.Visible = 'off';


            end
       

        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
           clear sound; % stop any audio
           % skip to next question
           app.CurrentQuestionIndex = app.CurrentQuestionIndex + 1;
           % if the quiz is finished, display results. otherwise continue)
           if app.CurrentQuestionIndex > length(app.QuizData)
               showResults(app);
           else
               displayQuestion(app);
               app.ButtonGroup.SelectedObject = []; % clear selection from button
           end
        end

        function showResults(app)
            app.QuestionLabel.Visible = 'off';
            app.OptionButton1.Visible = 'off';
            app.OptionButton2.Visible = 'off';
            app.OptionButton3.Visible = 'off';
            app.SkipButton.Visible = 'off';
            app.Slider.Visible = 'off';         
            app.SliderLabel.Visible = 'off';     
            app.SubmitRatingButton.Visible = 'off';
            app.PlayAudioButton.Visible = "off";
            app.ResultsPanel.Visible = 'on';
            app.Image2.Visible = 'off';
            app.Image3.Visible = 'off';
            app.Image4.Visible = 'off';
            app.Switch.Visible = 'off';
            app.Switch_6.Visible = 'off';
            app.Switch_3.Visible = 'off';
            app.Switch_5.Visible = 'off';
            app.Switch_4.Visible = 'off';
            app.Switch_2.Visible = 'off';
            app.SwitchLabel.Visible = 'off';
            app.Switch_6Label.Visible = 'off';
            app.Switch_3Label.Visible = 'off';
            app.Switch_5Label.Visible = 'off';
            app.Switch_4Label.Visible = 'off';
            app.Switch_2Label.Visible = 'off';
            app.SubmitButton.Visible = 'off';
            app.QuestionNumberLabel.Visible = 'off';
            app.SubmitSpinnerButton.Visible = 'off';
            app.Spinner.Visible = 'off';
            
            % graph in results 
            groupNames = {' Auditory ', ' Visual ', ' Kinesthetic '};
            groupCounts = [11, 13, 6];
            cla(app.UIAxes);
            bar(app.UIAxes, groupCounts);
                      
            app.UIAxes.XTick = 1:3;
            app.UIAxes.XTickLabel = groupNames;
            app.UIAxes.Title.String = 'Sample Brown University Learner Type';
            titlePos = app.UIAxes.Title.Position; 
            titlePos(2) = titlePos(2) + 2; 
            app.UIAxes.Title.Position = titlePos;
            app.UIAxes.YLabel.String = 'Number of People';
            app.UIAxes.XLabel.String = 'Learning Style';
            app.UIAxes.YLim = [0 15]; 
            
            percentLabels = {'36.7%', '43.3%', '20.0%'};
            for i = 1:length(groupCounts)
                text(app.UIAxes, i, groupCounts(i) + 0.5,percentLabels{i},'HorizontalAlignment', 'center','FontSize', 12,'FontName', 'Krungthep');
            end


            % determine highest scores 
            types = fieldnames(app.Scores);
            values = cell2mat(struct2cell(app.Scores));
            maxValue = max(values);
            maxIndices = values == maxValue;
            topTypes = types (maxIndices);

           
            % tied styles 
            if numel(topTypes) == 1
                app.LearningStyleResultsLabel.Text = ['Your dominant learning type: ' topTypes{1} '!'];

            else 
                app.LearningStyleResultsLabel.Text = ['You are a mix of a: ' strjoin(topTypes, ' & ') ' learner!'];
            end

             switch numel(topTypes)
                 % set gifs and descriptions for getting one style
                 case 1
                     mainType = topTypes{1};
                     switch mainType
                     case 'Visual'
                        app.DescriptionLabel.Value = "You absorb information best by seeing and reviewing information. Written notes, diagrams, organized layouts, and graphs help you absorb material in a spatial and easily comprehensible manner. You likely benefit from rereading notes, studying charts, and using color-coded systems to organize your thoughts. To maximize your learning, consider breaking down complex information into visual formats.";
                        app.Image.ImageSource = "visualgif.gif";
                
                     case 'Auditory'
                        app.DescriptionLabel.Value = "You absorb information best through listening. Highly effective study methods for you include reviewing lectures, participating in discussions, listening to podcasts, and even reading aloud. You find that verbal explanations and storytelling help you to understand and retain concepts. To maximize your learning, consider incorporating more audio-based strategies into your study habits.";
                        app.Image.ImageSource = "auditory.gif";
                      
                     case 'Kinesthetic'
                        app.DescriptionLabel.Value = "You absorb information best through hands-on experience and physical movement. Some important strategies for strengthening your understanding and memory include building models, using gestures, or being active while studying. Compared to traditional lecture-style learning, you find truly immersing yourself in the material is the most effective way of learning it. To maximize your learning, consider finding ways to connect movement with studying to make learning feel more natural.";
                        app.Image.ImageSource = "kinesthetic.gif";
                     end
             
                 % set gifs and descriptions for tied styles
                 case 2
                     pair = sort(topTypes) % alphabetically sort for consistency
                         
                     if isequal(pair, {'Visual', 'Auditory'})
                         app.DescriptionLabel.Text = 'You absorb information best through listening. Highly effective study methods for you include reviewing lectures, participating in discussions, listening to podcasts, and even reading aloud. You find that verbal explanations and storytelling help you to understand and retain concepts. To maximize your learning, consider incorporating more audio-based strategies into your study habits. You also absorb information best by seeing and reviewing information. Written notes, diagrams, organized layouts, and graphs help you absorb material in a spatial and easily comprehensible manner. You likely benefit from rereading notes, studying charts, and using color-coded systems to organize your thoughts. To maximize your learning, consider breaking down complex information into visual formats.';
                         app.Image.ImageSource = 'mixed.gif';
                     elseif isequal(pair, {'Kinesthetic', 'Visual'})
                         app.DescriptionLabel.Text = 'You absorb information best by seeing and reviewing information. Written notes, diagrams, organized layouts, and graphs help you absorb material in a spatial and easily comprehensible manner. You likely benefit from rereading notes, studying charts, and using color-coded systems to organize your thoughts. To maximize your learning, consider breaking down complex information into visual formats. You also absorb information best through hands-on experience and physical movement. Some important strategies for strengthening your understanding and memory include building models, using gestures, or being active while studying. Compared to traditional lecture-style learning, you find truly immersing yourself in the material is the most effective way of learning it. To maximize your learning, consider finding ways to connect movement with studying to make learning feel more natural.';
                         app.Image.ImageSource = 'mixed.gif';
                     elseif isequal(pair, {'Auditory', 'Kinesthetic'})
                         app.DescriptionLabel.Text = 'You absorb information best through listening. Highly effective study methods for you include reviewing lectures, participating in discussions, listening to podcasts, and even reading aloud. You find that verbal explanations and storytelling help you to understand and retain concepts. To maximize your learning, consider incorporating more audio-based strategies into your study habits. You also absorb information best through hands-on experience and physical movement. Some important strategies for strengthening your understanding and memory include building models, using gestures, or being active while studying. Compared to traditional lecture-style learning, you find truly immersing yourself in the material is the most effective way of learning it. To maximize your learning, consider finding ways to connect movement with studying to make learning feel more natural.';
                         app.Image.ImageSource = 'mixed.gif';
                     
                     end
                
                 % set gifs and descriptions for tying all 3 styles
                 case 3
                         app.DescriptionLabel.Text = 'You absorb information best through listening, seeing and reviewing information, and hands-on experience. Highly effective study methods for you include reviewing lectures, participating in discussions, listening to podcasts, writing notes, using color coded systems, building models, and being active while studying. To maximize your learning, consider taking a balanced approach between methods from all three learning styles.';
                         app.Image.ImageSource = 'mixed.gif';
             end

        end

        % Button pushed function: SubmitRatingButton
        function SubmitRatingButtonPushed(app, event)
            updateSliderScore(app); % update score based on slider input


        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            q = app.QuizData(app.CurrentQuestionIndex); % retrieve current question
            [y, Fs] = audioread("audioq.wav"); % load audio file
               sound(y, Fs) % play audio
  
        end

        % Callback function
        function SwitchValueChanged(app, event)
            % this can be implemented if switch interactions are needed
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
           processSwitchSelections(app); % process user-selected switch values
           % move to next question
           app.CurrentQuestionIndex = app.CurrentQuestionIndex + 1;
           % if the quiz is finished, display results. otherwise continue)
           if app.CurrentQuestionIndex > length(app.QuizData)
               showResults(app);
           else
               displayQuestion(app);
           end


        end

        % Button pushed function: StartTimerButton
        function StartTimerButtonPushed(app, event)
            q = app.QuizData(app.CurrentQuestionIndex); % retrieve current question
            [y, Fs] = audioread("timer.wav"); % load timer file
               sound(y, Fs) % play timer
               
        end

        % Button pushed function: StopTimerButton
        function StopTimerButtonPushed(app, event)
            clear sound; % stop any playing audio immediately

        end

        % Button pushed function: SubmitSpinnerButton
        function SubmitSpinnerButtonPushed(app, event)
            clear sound; % stop any audio
            spinnerValue = app.Spinner.Value; % get spinner selection
            % assign score based on spinner value
            if spinnerValue >= 4 && spinnerValue <= 6
                app.Scores.Auditory = app.Scores.Auditory + 1;
            else
                app.Scores.None = app.Scores.None;
            end
            % move to the next question
            app.CurrentQuestionIndex = app.CurrentQuestionIndex + 1;

           % if the quiz is finished, display results. otherwise continue)
           if app.CurrentQuestionIndex > length(app.QuizData)
               showResults(app);
           else
               displayQuestion(app);
           end


        end

        % Button pushed function: RevealWaldoButton
        function RevealWaldoButtonPushed(app, event)
            app.WaldoCircle.Visible = 'on'; % reveal waldo's location when button is clicked
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptionButton1 = uibutton(app.UIFigure, 'push');
            app.OptionButton1.ButtonPushedFcn = createCallbackFcn(app, @OptionButton1Pushed, true);
            app.OptionButton1.Text = 'Option Button 1';
            app.QuestionLabel = uilabel(app.UIFigure);
            app.QuestionLabel.Text = 'Question Label';
            app.OptionButton2 = uibutton(app.UIFigure, 'push');
            app.OptionButton2.ButtonPushedFcn = createCallbackFcn(app, @OptionButton2Pushed, true);
            app.OptionButton2.Text = 'Option Button 2';
            app.OptionButton3 = uibutton(app.UIFigure, 'push');
            app.OptionButton3.ButtonPushedFcn = createCallbackFcn(app, @OptionButton3Pushed, true);
            app.OptionButton3.Text = 'Option Button 3';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Text = 'Skip';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.ResultsPanel.Visible = 'off';
            app.UIAxes = uiaxes(app.ResultsPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.ResultsPanel);
            app.LearningStyleResultsLabel = uilabel(app.ResultsPanel);
            app.LearningStyleResultsLabel.Text = 'Learning Style Results';
            app.TextAreaLabel = uilabel(app.ResultsPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.DescriptionLabel = uitextarea(app.ResultsPanel);
            app.SubmitRatingButton = uibutton(app.UIFigure, 'push');
            app.SubmitRatingButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitRatingButtonPushed, true);
            app.SubmitRatingButton.Text = 'Submit Rating';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'coffee shop.jpg');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'outdoor.jpg');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'colorful.jpg');
            app.Switch_6Label = uilabel(app.UIFigure);
            app.Switch_6Label.Text = '"When recalling information, I..."';
            app.Switch_6 = uiswitch(app.UIFigure, 'slider');
            app.Switch_6.Items = {'hear the speaker''s voice in my head', 'remember the physical actions I performed'};
            app.Switch_6.Value = 'hear the speaker''s voice in my head';
            app.Switch_3Label = uilabel(app.UIFigure);
            app.Switch_3Label.Text = '"To understand instructions, I prefer..."';
            app.Switch_5Label = uilabel(app.UIFigure);
            app.Switch_5Label.Text = '"When studying, I focus better with..."';
            app.Switch_5 = uiswitch(app.UIFigure, 'slider');
            app.Switch_5.Items = {'highlighters and mind maps', 'fidget tools or pacing around'};
            app.Switch_5.Value = 'highlighters and mind maps';
            app.Switch_4Label = uilabel(app.UIFigure);
            app.Switch_4Label.Text = '"To memorize information, I rely on..."';
            app.Switch_4 = uiswitch(app.UIFigure, 'slider');
            app.Switch_4.Items = {'repeating it aloud or listening to recordings', 'writing it down or using flashcards'};
            app.Switch_4.Value = 'repeating it aloud or listening to recordings';
            app.Switch_2Label = uilabel(app.UIFigure);
            app.Switch_2Label.Text = '"I learn best when I..."';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'watch demonstrations or videos', 'try it myself with hands on practice'};
            app.Switch_2.Value = 'watch demonstrations or videos';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.QuestionNumberLabel = uilabel(app.UIFigure);
            app.QuestionNumberLabel.Text = 'Question Number';
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = '"When learning a new concept, I prefer..."';
            app.StartTimerButton = uibutton(app.UIFigure, 'push');
            app.StartTimerButton.ButtonPushedFcn = createCallbackFcn(app, @StartTimerButtonPushed, true);
            app.StartTimerButton.Visible = 'off';
            app.StartTimerButton.Text = 'Start Timer';
            app.StopTimerButton = uibutton(app.UIFigure, 'push');
            app.StopTimerButton.ButtonPushedFcn = createCallbackFcn(app, @StopTimerButtonPushed, true);
            app.StopTimerButton.Visible = 'off';
            app.StopTimerButton.Text = 'Stop Timer';
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'wheres waldo game.jpg');
            app.Switch_3 = uiswitch(app.UIFigure, 'slider');
            app.Switch_3.Items = {'someone explaining them step by step', 'jumping in and figuring it out as I go'};
            app.Switch_3.Value = 'someone explaining them step by step';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'waldo lol.jpeg');
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'learningstylequiz.jpg');
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 5];
            app.Slider.MajorTicks = [0 1 2 3 4 5];
            app.Slider.MajorTickLabels = {'0', '1', '2', '3', '4', '5'};
            app.Slider.MinorTicks = [0.5 1.5 2.5 3.5 4.5];
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.SubmitSpinnerButton = uibutton(app.UIFigure, 'push');
            app.SubmitSpinnerButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitSpinnerButtonPushed, true);
            app.SubmitSpinnerButton.Text = 'Submit';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Limits = [0 100];
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'a podcast or verbal explanation', 'a diagram or infographic'};
            app.Switch.Value = 'a podcast or verbal explanation';
            app.RevealWaldoButton = uibutton(app.UIFigure, 'push');
            app.RevealWaldoButton.ButtonPushedFcn = createCallbackFcn(app, @RevealWaldoButtonPushed, true);
            app.RevealWaldoButton.Text = 'Reveal Waldo';
            app.WaldoCircle = uilabel(app.UIFigure);
            app.WaldoCircle.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = learningquiz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end